classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image2                   matlab.ui.control.Image
        PopRockButton            matlab.ui.control.Button
        StopAudioButton          matlab.ui.control.Button
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        PlotFilteredButton       matlab.ui.control.Button
        PlotOriginalButton       matlab.ui.control.Button
        ApplyButton              matlab.ui.control.Button
        PlaybackSpeedLabel       matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        PlayFilteredAudioButton  matlab.ui.control.Button
        PlayOriginalAudioButton  matlab.ui.control.Button
        ChooseFilterTypeLabel    matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
        SoundEditField           matlab.ui.control.EditField
        SoundEditFieldLabel      matlab.ui.control.Label
        HZSliderLabel_9          matlab.ui.control.Label
        HZSliderLabel_8          matlab.ui.control.Label
        HZSliderLabel_7          matlab.ui.control.Label
        HZSliderLabel_6          matlab.ui.control.Label
        HZSliderLabel_5          matlab.ui.control.Label
        HZSliderLabel_4          matlab.ui.control.Label
        HZSliderLabel_3          matlab.ui.control.Label
        HZSliderLabel_2          matlab.ui.control.Label
        HZSlider_9               matlab.ui.control.Slider
        HZSlider_8               matlab.ui.control.Slider
        HZSlider_7               matlab.ui.control.Slider
        HZSlider_6               matlab.ui.control.Slider
        HZSlider_5               matlab.ui.control.Slider
        HZSlider_4               matlab.ui.control.Slider
        HZSlider_3               matlab.ui.control.Slider
        HZSlider_2               matlab.ui.control.Slider
        HZSlider                 matlab.ui.control.Slider
        HZSliderLabel            matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
        lLabel                   matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties section
    properties (Access = private)
        Ip % Audio data
        Fs % Sampling frequency
        path %Audio Path
        OpSound %Output Sound 
        CheckConditions  = 0; %check wether the user enters all req or not
        CheckAudio = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fileName, app.path] = uigetfile('*.wav','Select an Audio file');
            % Check if the user clicked Cancel
            if isequal(fileName, 0)
                return; % Exit the function
            end
            app.CheckAudio = 1;
            [app.Ip, app.Fs] = audioread(fileName);
            app.SoundEditField.Value = app.path;
            plot(app.UIAxes,app.Ip, 'Color', [0 0 0]);
            plot(app.UIAxes_2,abs(fftshift(fft(app.Ip)) / app.Fs), 'Color', [0 0 0]);
        end

        % Callback function
        function AudioInfoValueChanging(app, event)
           
        end

        % Callback function
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Value changing function: HZSlider
        function HZSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: SoundEditField
        function SoundEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: HZSlider_9
        function HZSlider_9ValueChanging(app, event)
            changingValue = event.Value;
        end

        % Callback function
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;            
        end

        % Callback function: ApplyButton, HZSlider
        function ApplyFilterButtonPushed(app, event)
            if app.CheckAudio == 0    
                msgbox('Please Choose An Audio And Try Again!','Error','error');
                return;
            end
            app.CheckConditions = 1;
            %%%%%%%%%%%%%%%%%________ Calculate Fs Normalized ________%%%%%%%%%%%%%%%%%%%%%%%%%
            F_normalized = app.Fs / 2;
            %%%%%%%%%%%%%________ Calculate Frequencies Normalized ________%%%%%%%%%%%%%%%%%%%%
            f1 = 170 / F_normalized;
            f2 = [170 / F_normalized, 310 / F_normalized];
            f3 = [310 / F_normalized, 600 / F_normalized];
            f4 = [600 / F_normalized, 1000 / F_normalized];
            f5 = [1000 / F_normalized, 3000 / F_normalized];
            f6 = [3000 / F_normalized, 6000 / F_normalized];
            f7 = [6000 / F_normalized, 12000 / F_normalized];
            f8 = [12000 / F_normalized, 14000 / F_normalized];
            f9 = [14000 / F_normalized, 16000 / F_normalized];
            %%%%%%%%%%%%%%%%%%____Convert DB to WT____%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            gain1 = 10^(app.HZSlider.Value/10);
            gain2 = 10^(app.HZSlider_2.Value/10);
            gain3 = 10^(app.HZSlider_3.Value/10);
            gain4 = 10^(app.HZSlider_4.Value/10);
            gain5 = 10^(app.HZSlider_5.Value/10);
            gain6 = 10^(app.HZSlider_6.Value/10);
            gain7 = 10^(app.HZSlider_7.Value/10);
            gain8 = 10^(app.HZSlider_8.Value/10);
            gain9 = 10^(app.HZSlider_9.Value/10);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            switch app.ListBox.Value 
                case 'FIR'

                    Order = 80;
                    TF1 = fir1(Order, f1, blackman(Order + 1));
                    Op1 = filter(TF1, 1, app.Ip);
                    Op1 = Op1 * gain1;
                   
                    TF2 = fir1(Order, f2, blackman(Order + 1));
                    Op2 = filter(TF2, 1, app.Ip);
                    Op2 = Op2 * gain2;
                      
                    TF3=fir1(Order,f3,blackman(Order+1));
                    Op3=filter(TF3,1,app.Ip);
                    Op3=Op3*gain3;
                   
                    TF4=fir1(Order,f4,blackman(Order+1));
                    Op4=filter(TF4,1,app.Ip);
                    Op4=Op4*gain4;
                   
                    TF5=fir1(Order,f5,blackman(Order+1));
                    Op5=filter(TF5,1,app.Ip);
                    Op5=Op5*gain5;

                    TF6=fir1(Order,f6,blackman(Order+1));
                    Op6=filter(TF6,1,app.Ip);
                    Op6=Op6*gain6;
                    
                    TF7=fir1(Order,f7,blackman(Order+1));
                    Op7=filter(TF7,1,app.Ip);
                    Op7=Op7*gain7;
                    
                    TF8=fir1(Order,f8,blackman(Order+1));
                    Op8=filter(TF8,1,app.Ip);
                    Op8=Op8*gain8;

                    TF9=fir1(Order,f9,blackman(Order+1));
                    Op9=filter(TF9,1,app.Ip);
                    Op9=Op9*gain9;
                    
                    app.OpSound = [Op1 + Op2 + Op3 + Op4 + Op5 + Op6 + Op7 + Op8 + Op9];

                case 'IIR'
                    %%%%%%%%%%%%%________ Define IIR Butter Worth Filters _________%%%%%%%%%%%%%%%%%%%%
                    [b1, a1] = butter( 7, f1, 'low');
                    [b2, a2] = butter( 3, f2, 'bandpass');
                    [b3, a3] = butter( 5, f3, 'bandpass');
                    [b4, a4] = butter( 5, f4, 'bandpass');
                    [b5, a5] = butter( 7, f5, 'bandpass');
                    [b6, a6] = butter( 7, f6, 'bandpass');
                    [b7, a7] = butter(14, f7, 'bandpass');
                    [b8, a8] = butter(14, f8, 'bandpass');
                    [b9, a9] = butter(14, f9, 'bandpass');
                    
                    Op1 = filter(b1,a1,app.Ip);
                    Op1 = Op1 * gain1;

                    Op2 = filter(b2,a2,app.Ip);
                    Op2 = Op2 * gain2;
                    
                    Op3 = filter(b3,a3,app.Ip);
                    Op3 = Op3 * gain3;
                    
                    Op4 = filter(b4,a4,app.Ip);
                    Op4 = Op4 * gain4;
                    
                    Op5 = filter(b5,a5,app.Ip);
                    Op5 = Op5 * gain5;
                    
                    Op6 = filter(b6,a6,app.Ip);
                    Op6 = Op6 * gain6;
                    
                    Op7 = filter(b7,a7,app.Ip);
                    Op7 = Op7 * gain7;
                    
                    Op8 = filter(b8,a8,app.Ip);
                    Op8 = Op8 * gain8;
                    
                    Op9 = filter(b9,a9,app.Ip);
                    Op9 = Op9 * gain9;
                    
                    app.OpSound = [Op1 + Op2 + Op3 + Op4 + Op5 + Op6 + Op7 + Op8 + Op9]; 

            end
            NewFs = app.Slider.Value * app.Fs;
            plot(app.UIAxes,app.OpSound, 'Color', [0 0 0]);
            plot(app.UIAxes_2,abs(fftshift(fft(app.OpSound)) / NewFs), 'Color', [0 0 0]);
        end

        % Button pushed function: PlayOriginalAudioButton
        function PlayOriginalAudioButtonPushed(app, event)
             if app.CheckAudio == 1
                plot(app.UIAxes,app.Ip, 'Color', [0 0 0]);
                NewFs = app.Slider.Value * app.Fs;
                plot(app.UIAxes_2,abs(fftshift(fft(app.Ip)) / app.Fs), 'Color', [0 0 0]);
                sound(app.Ip, NewFs);
            else 
                msgbox('Please Choose An Audio And Try Again!','Error','error');
            end
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            if app.CheckConditions == 1
                plot(app.UIAxes,app.OpSound, 'Color', [0 0 0]);
                NewFs = app.Slider.Value * app.Fs;
                plot(app.UIAxes_2,abs(fftshift(fft(app.OpSound)) / NewFs), 'Color', [0 0 0]);
                sound(app.OpSound, NewFs);
            else 
                msgbox('Please Choose Your Requirements, Then Click Apply Filter First!','Error','error');
            end
        end

        % Callback function
        function HZSlider_9ValueChanged(app, event)
            value = app.HZSlider_9.Value;
        end

        % Button pushed function: PlotOriginalButton
        function PlotOriginalButtonPushed(app, event)
            if app.CheckAudio == 1
                plot(app.UIAxes,app.Ip, 'Color', [0 0 0]);
                plot(app.UIAxes_2,abs(fftshift(fft(app.Ip)) / app.Fs), 'Color', [0 0 0]);
            else
                msgbox('Please Choose An Audio And Try Again!','Error','error');
            end
        end

        % Button pushed function: PlotFilteredButton
        function PlotFilteredButtonPushed(app, event)
            if app.CheckConditions == 1
                NewFs = app.Slider.Value * app.Fs;
                plot(app.UIAxes,app.OpSound, 'Color', [0 0 0]);
                plot(app.UIAxes_2,abs(fftshift(fft(app.OpSound)) / NewFs), 'Color', [0 0 0]);
            else 
                msgbox('Please Choose Your Requirements, Then Click Apply Filter First!','Error','error');
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain Audio')
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domian Audio')
            xlabel(app.UIAxes_2, 'Frequency')
            zlabel(app.UIAxes_2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EQ.png');
            app.lLabel = uilabel(app.UIFigure);
            app.lLabel.Text = 'l';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.HZSliderLabel = uilabel(app.UIFigure);
            app.HZSliderLabel.Text = '0-170HZ';
            app.HZSlider = uislider(app.UIFigure);
            app.HZSlider.Limits = [-15 15];
            app.HZSlider.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.HZSlider.ValueChangingFcn = createCallbackFcn(app, @HZSliderValueChanging, true);
            app.HZSlider_2 = uislider(app.UIFigure);
            app.HZSlider_2.Limits = [-15 15];
            app.HZSlider_2.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_3 = uislider(app.UIFigure);
            app.HZSlider_3.Limits = [-15 15];
            app.HZSlider_3.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_4 = uislider(app.UIFigure);
            app.HZSlider_4.Limits = [-15 15];
            app.HZSlider_4.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_5 = uislider(app.UIFigure);
            app.HZSlider_5.Limits = [-15 15];
            app.HZSlider_5.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_6 = uislider(app.UIFigure);
            app.HZSlider_6.Limits = [-15 15];
            app.HZSlider_6.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_7 = uislider(app.UIFigure);
            app.HZSlider_7.Limits = [-15 15];
            app.HZSlider_7.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_8 = uislider(app.UIFigure);
            app.HZSlider_8.Limits = [-15 15];
            app.HZSlider_8.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_9 = uislider(app.UIFigure);
            app.HZSlider_9.Limits = [-15 15];
            app.HZSlider_9.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HZSlider_9.ValueChangedFcn = createCallbackFcn(app, @HZSlider_9ValueChanging, true);
            app.HZSliderLabel_2 = uilabel(app.UIFigure);
            app.HZSliderLabel_2.Text = '170-300HZ';
            app.HZSliderLabel_3 = uilabel(app.UIFigure);
            app.HZSliderLabel_3.Text = '300-610HZ';
            app.HZSliderLabel_4 = uilabel(app.UIFigure);
            app.HZSliderLabel_4.Text = '610-1005HZ';
            app.HZSliderLabel_5 = uilabel(app.UIFigure);
            app.HZSliderLabel_5.Text = '3-6KHZ';
            app.HZSliderLabel_6 = uilabel(app.UIFigure);
            app.HZSliderLabel_6.Text = '6-12KHZ';
            app.HZSliderLabel_7 = uilabel(app.UIFigure);
            app.HZSliderLabel_7.Text = '12-14KHZ';
            app.HZSliderLabel_8 = uilabel(app.UIFigure);
            app.HZSliderLabel_8.Text = '14-20KHZ';
            app.HZSliderLabel_9 = uilabel(app.UIFigure);
            app.HZSliderLabel_9.Text = '1.5-3KHZ';
            app.SoundEditFieldLabel = uilabel(app.UIFigure);
            app.SoundEditFieldLabel.Text = 'Sound ';
            app.SoundEditField = uieditfield(app.UIFigure, 'text');
            app.SoundEditField.ValueChangingFcn = createCallbackFcn(app, @SoundEditFieldValueChanging, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'FIR', 'IIR'};
            app.ListBox.Value = 'FIR';
            app.ChooseFilterTypeLabel = uilabel(app.UIFigure);
            app.ChooseFilterTypeLabel.Text = 'Choose Filter Type';
            app.PlayOriginalAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalAudioButtonPushed, true);
            app.PlayOriginalAudioButton.Text = 'Play Original Audio';
            app.PlayFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0.5 3];
            app.Slider.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.Slider.Value = 1;
            app.PlaybackSpeedLabel = uilabel(app.UIFigure);
            app.PlaybackSpeedLabel.Text = 'Playback Speed';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyButton.Text = 'Apply ';
            app.PlotOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlotOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOriginalButtonPushed, true);
            app.PlotOriginalButton.Text = 'Plot Original ';
            app.PlotFilteredButton = uibutton(app.UIFigure, 'push');
            app.PlotFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFilteredButtonPushed, true);
            app.PlotFilteredButton.Text = 'Plot Filtered ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.PopRockButton = uibutton(app.UIFigure, 'push');
            app.PopRockButton.Text = 'Pop/Rock';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'hankasha.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end